classdef robotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IRBRollTextArea            matlab.ui.control.TextArea
        RollTextAreaLabel_2        matlab.ui.control.Label
        IRBPitchTextArea           matlab.ui.control.TextArea
        PitchTextAreaLabel_2       matlab.ui.control.Label
        IRBYawTextArea             matlab.ui.control.TextArea
        YawTextAreaLabel_2         matlab.ui.control.Label
        IRBZTextArea               matlab.ui.control.TextArea
        ZTextAreaLabel_2           matlab.ui.control.Label
        IRBYTextArea               matlab.ui.control.TextArea
        YTextAreaLabel_2           matlab.ui.control.Label
        IRBXTextArea               matlab.ui.control.TextArea
        XTextAreaLabel_2           matlab.ui.control.Label
        IRBZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel_2          matlab.ui.control.Label
        IRBYEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel_2          matlab.ui.control.Label
        IRBJoint1Slider            matlab.ui.control.Slider
        Joint1SliderLabel_2        matlab.ui.control.Label
        IRBJoint2Slider            matlab.ui.control.Slider
        Joint2SliderLabel_2        matlab.ui.control.Label
        IRBJoint3Slider            matlab.ui.control.Slider
        Joint3SliderLabel_2        matlab.ui.control.Label
        IRBJoint4Slider            matlab.ui.control.Slider
        Joint4SliderLabel_2        matlab.ui.control.Label
        IRBJoint5Slider            matlab.ui.control.Slider
        Joint5SliderLabel_2        matlab.ui.control.Label
        IRBJoint6Slider            matlab.ui.control.Slider
        Joint6SliderLabel_2        matlab.ui.control.Label
        IRBCartesianMovementLabel  matlab.ui.control.Label
        IRBPoseLabel               matlab.ui.control.Label
        IRBJointAngleSliderLabel   matlab.ui.control.Label
        IRBMOVEButton              matlab.ui.control.Button
        IRBXEditField              matlab.ui.control.NumericEditField
        IRB1200Label               matlab.ui.control.Label
        UR3RollTextArea            matlab.ui.control.TextArea
        RollTextAreaLabel          matlab.ui.control.Label
        UR3PitchTextArea           matlab.ui.control.TextArea
        PitchTextAreaLabel         matlab.ui.control.Label
        UR3YawTextArea             matlab.ui.control.TextArea
        YawTextAreaLabel           matlab.ui.control.Label
        UR3ZTextArea               matlab.ui.control.TextArea
        ZTextAreaLabel             matlab.ui.control.Label
        UR3CartesianMovementLabel  matlab.ui.control.Label
        UR3PoseLabel               matlab.ui.control.Label
        UR3JointAngleSliderLabel   matlab.ui.control.Label
        UR3YTextArea               matlab.ui.control.TextArea
        YTextAreaLabel             matlab.ui.control.Label
        UR3XTextArea               matlab.ui.control.TextArea
        XTextAreaLabel             matlab.ui.control.Label
        UR3MOVEButton              matlab.ui.control.Button
        UR3ZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        UR3YEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        UR3XEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        UR3Label                   matlab.ui.control.Label
        UR3Joint1Slider            matlab.ui.control.Slider
        Joint1SliderLabel          matlab.ui.control.Label
        UR3Joint2Slider            matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        UR3Joint3Slider            matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        UR3Joint4Slider            matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        UR3Joint5Slider            matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        UR3Joint6Slider            matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
    end

    properties (Access = private)
        irb 
        ur3robot 
        qIRB = [0 0 0 0	0 0];
        qUR3 = [0 0 0 0	0 0];
    end 
    
    methods (Access = private)
        
        function UpdateUR3Pose(app)
            pose = app.ur3robot.model.fkine(app.qUR3).T;
            
            rpy = tr2rpy(pose);
            app.UR3RollTextArea.Value = num2str(rpy(1));
            app.UR3PitchTextArea.Value = num2str(rpy(2));
            app.UR3YawTextArea.Value = num2str(rpy(3));

            app.UR3XTextArea.Value = num2str(pose(1, 4));
            app.UR3YTextArea.Value = num2str(pose(2, 4));
            app.UR3ZTextArea.Value = num2str(pose(3, 4));
        end

        function UpdateIRBPose(app)
            pose = app.irb.model.fkine(app.qIRB).T;
            
            rpy = tr2rpy(pose);
            app.IRBRollTextArea.Value = num2str(rpy(1));
            app.IRBPitchTextArea.Value = num2str(rpy(2));
            app.IRBYawTextArea.Value = num2str(rpy(3));
            
            app.IRBXTextArea.Value = num2str(pose(1, 4));
            app.IRBYTextArea.Value = num2str(pose(2, 4));
            app.IRBZTextArea.Value = num2str(pose(3, 4));
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.irb = IRB1200();
            app.ur3robot = UR3();
            app.UpdateUR3Pose();
            app.UpdateIRBPose();
        end

        % Value changed function: UR3Joint1Slider
        function UR3Joint1SliderValueChanged(app, event)
            value = app.UR3Joint1Slider.Value;
            app.qUR3(1) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Value changed function: UR3Joint2Slider
        function UR3Joint2SliderValueChanged(app, event)
            value = app.UR3Joint2Slider.Value;
            app.qUR3(2) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Callback function: UIFigure, UR3Joint3Slider
        function UR3Joint3SliderValueChanged(app, event)
            value = app.UR3Joint3Slider.Value;
            app.qUR3(3) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Value changed function: UR3Joint4Slider
        function UR3Joint4SliderValueChanged(app, event)
            value = app.UR3Joint4Slider.Value;
            app.qUR3(4) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Value changed function: UR3Joint5Slider
        function UR3Joint5SliderValueChanged(app, event)
            value = app.UR3Joint5Slider.Value;
            app.qUR3(5) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Value changed function: UR3Joint6Slider
        function UR3Joint6SliderValueChanged(app, event)
            value = app.UR3Joint6Slider.Value;
            app.qUR3(6) = deg2rad(value);
            app.ur3robot.model.animate(app.qUR3);
            app.UpdateUR3Pose();
        end

        % Button pushed function: UR3MOVEButton
        function UR3MOVEButtonPushed(app, event)
            x = app.UR3XEditField.Value;
            y = app.UR3YEditField.Value;
            z = app.UR3ZEditField.Value;

            qEnd = app.ur3robot.model.ikcon(transl([x y z]), app.qUR3);
            endPose = app.ur3robot.model.fkine(qEnd).T;
            if abs(x-endPose(1,4)) < 0.05 && abs(y-endPose(2,4)) < 0.05 && abs(z-endPose(3,4)) < 0.05
                steps = 20;
                trajectory = jtraj(app.qUR3,qEnd,steps);
                for i = 1:steps
                    app.ur3robot.model.animate(trajectory(i,:));
                    pause(0.005);
                    app.qIRB = app.ur3robot.model.getpos;
                    app.UpdateUR3Pose();
                end
            else
                msg = 'No UR3 joint configuration was found for given coordinate';
                title = 'ERROR: UR3 COORDINATE UNREACHABLE';
                msgbox(msg, title);
            end

        end

        % Value changed function: IRBJoint1Slider
        function IRBJoint1SliderValueChanged(app, event)
            value = app.IRBJoint1Slider.Value;
            app.qIRB(1) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Value changed function: IRBJoint2Slider
        function IRBJoint2SliderValueChanged(app, event)
            value = app.IRBJoint2Slider.Value;
            app.qIRB(2) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Value changed function: IRBJoint3Slider
        function IRBJoint3SliderValueChanged(app, event)
            value = app.IRBJoint3Slider.Value;
            app.qIRB(3) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Value changed function: IRBJoint4Slider
        function IRBJoint4SliderValueChanged(app, event)
            value = app.IRBJoint4Slider.Value;
            app.qIRB(4) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Value changed function: IRBJoint5Slider
        function IRBJoint5SliderValueChanged(app, event)
            value = app.IRBJoint5Slider.Value;
            app.qIRB(5) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Value changed function: IRBJoint6Slider
        function IRBJoint6SliderValueChanged(app, event)
            value = app.IRBJoint6Slider.Value;
            app.qIRB(6) = deg2rad(value);
            app.irb.model.animate(app.qIRB);
            app.UpdateIRBPose();
        end

        % Button pushed function: IRBMOVEButton
        function IRBMOVEButtonPushed(app, event)
            x = app.IRBXEditField.Value;
            y = app.IRBYEditField.Value;
            z = app.IRBZEditField.Value;

            qEnd = app.irb.model.ikcon(transl([x y z]), app.qIRB);
            endPose = app.irb.model.fkine(qEnd).T;
            if abs(x-endPose(1,4)) < 0.5 && abs(y-endPose(2,4)) < 0.5 && abs(z-endPose(3,4)) < 0.5
                steps = 20;
                trajectory = jtraj(app.qIRB,qEnd,steps);
                for i = 1:steps
                    app.irb.model.animate(trajectory(i,:));
                    pause(0.005);
                    app.qIRB = app.irb.model.getpos;
                    app.UpdateIRBPose();
                end
            else
                msg = 'No IRB1200 joint configuration was found for given coordinate';
                title = 'ERROR: IRB1200 COORDINATE UNREACHABLE';
                msgbox(msg, title);
            end
            % x = app.IRBXEditField.Value;
            % y = app.IRBYEditField.Value;
            % z = app.IRBZEditField.Value;
            % 
            % steps = 20;
            % q = app.irb.model.getpos;
            % T1 = app.irb.model.fkine(q).T; 
            % M = [1 1 1 zeros(1,3)];
            % x1 = [app.irb.model.fkine(q).t(1) app.irb.model.fkine(q).t(2) app.irb.model.fkine(q).t(3)]';
            % x2 = [x y z]';
            % deltaT = 0.05;
            % x = zeros(3,steps);
            % s = lspb(0,1,steps);
            % for i = 1:steps
            %     x(:,i) = x1*(1-s(i)) + s(i)*x2;
            % end
            % qMatrix = nan(steps,6);
            % qMatrix(1,:) = app.irb.model.ikine(T1, 'q0', q, 'mask', M);
            % 
            % for i = 1:steps-1
            %     xdot = (x(:,i+1) - x(:,i))/deltaT;   % calculates velocity at each position by getting change between next and current position and dividing by time step                          % Calculate velocity at discrete time step
            %     xdot = [xdot' 0 0 0];
            %     J = app.irb.model.jacob0(qMatrix(i,:));            % Get the Jacobian at the current state
            %     J = J(1:6,:);                           % Take only first 2 rows
            %     qdot = inv(J)*xdot'; % change in joint angles   (velocity of joint angles)                         % Solve velocitities via RMRC
            %     qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot';                   % Update next joint state
            % end
            % 
            % endPose = app.irb.model.fkine(qMatrix(steps,:)).T;
            % 
            % if abs(x-endPose(1,4)) < 0.05 && abs(y-endPose(2,4)) < 0.05 && abs(z-endPose(3,4)) < 0.05
            %     for i = 1:steps
            %         app.irb.model.animate(qMatrix(i,:)); % Animating the robot to move to the set joint configuratio
            %         pause(0.005);
            %     end
            % else
            %     msg = 'No IRB1200 joint configuration was found for given coordinate';
            %     title = 'ERROR: IRB1200 COORDINATE UNREACHABLE';
            %     msgbox(msg, title);
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UR3Joint3SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.UR3Joint6Slider = uislider(app.UIFigure);
            app.UR3Joint6Slider.Limits = [-360 360];
            app.UR3Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint6SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.UR3Joint5Slider = uislider(app.UIFigure);
            app.UR3Joint5Slider.Limits = [-360 360];
            app.UR3Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint5SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.UR3Joint4Slider = uislider(app.UIFigure);
            app.UR3Joint4Slider.Limits = [-360 360];
            app.UR3Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint4SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.UR3Joint3Slider = uislider(app.UIFigure);
            app.UR3Joint3Slider.Limits = [-360 360];
            app.UR3Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint3SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.UR3Joint2Slider = uislider(app.UIFigure);
            app.UR3Joint2Slider.Limits = [-360 360];
            app.UR3Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint2SliderValueChanged, true);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.UR3Joint1Slider = uislider(app.UIFigure);
            app.UR3Joint1Slider.Limits = [-360 360];
            app.UR3Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Joint1SliderValueChanged, true);
            app.UR3Label = uilabel(app.UIFigure);
            app.UR3Label.Text = 'UR3';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.UR3XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.UR3YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.UR3ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.UR3MOVEButton = uibutton(app.UIFigure, 'push');
            app.UR3MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @UR3MOVEButtonPushed, true);
            app.UR3MOVEButton.Text = 'MOVE';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X';
            app.UR3XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y';
            app.UR3YTextArea = uitextarea(app.UIFigure);
            app.UR3JointAngleSliderLabel = uilabel(app.UIFigure);
            app.UR3JointAngleSliderLabel.Text = 'Joint Angle Slider';
            app.UR3PoseLabel = uilabel(app.UIFigure);
            app.UR3PoseLabel.Text = 'Pose';
            app.UR3CartesianMovementLabel = uilabel(app.UIFigure);
            app.UR3CartesianMovementLabel.Text = 'Cartesian Movement';
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z';
            app.UR3ZTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw';
            app.UR3YawTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel = uilabel(app.UIFigure);
            app.PitchTextAreaLabel.Text = 'Pitch';
            app.UR3PitchTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel = uilabel(app.UIFigure);
            app.RollTextAreaLabel.Text = 'Roll';
            app.UR3RollTextArea = uitextarea(app.UIFigure);
            app.IRB1200Label = uilabel(app.UIFigure);
            app.IRB1200Label.Text = 'IRB1200';
            app.IRBXEditField = uieditfield(app.UIFigure, 'numeric');
            app.IRBMOVEButton = uibutton(app.UIFigure, 'push');
            app.IRBMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @IRBMOVEButtonPushed, true);
            app.IRBMOVEButton.Text = 'MOVE';
            app.IRBJointAngleSliderLabel = uilabel(app.UIFigure);
            app.IRBJointAngleSliderLabel.Text = 'Joint Angle Slider';
            app.IRBPoseLabel = uilabel(app.UIFigure);
            app.IRBPoseLabel.Text = 'Pose';
            app.IRBCartesianMovementLabel = uilabel(app.UIFigure);
            app.IRBCartesianMovementLabel.Text = 'Cartesian Movement';
            app.Joint6SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint6SliderLabel_2.Text = 'Joint 6';
            app.IRBJoint6Slider = uislider(app.UIFigure);
            app.IRBJoint6Slider.Limits = [-400 400];
            app.IRBJoint6Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint6SliderValueChanged, true);
            app.Joint5SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint5SliderLabel_2.Text = 'Joint 5';
            app.IRBJoint5Slider = uislider(app.UIFigure);
            app.IRBJoint5Slider.Limits = [-130 130];
            app.IRBJoint5Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint5SliderValueChanged, true);
            app.Joint4SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint4SliderLabel_2.Text = 'Joint 4';
            app.IRBJoint4Slider = uislider(app.UIFigure);
            app.IRBJoint4Slider.Limits = [-270 270];
            app.IRBJoint4Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint4SliderValueChanged, true);
            app.Joint3SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint3SliderLabel_2.Text = 'Joint 3';
            app.IRBJoint3Slider = uislider(app.UIFigure);
            app.IRBJoint3Slider.Limits = [-110 160];
            app.IRBJoint3Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint3SliderValueChanged, true);
            app.Joint2SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint2SliderLabel_2.Text = 'Joint 2';
            app.IRBJoint2Slider = uislider(app.UIFigure);
            app.IRBJoint2Slider.Limits = [-100 135];
            app.IRBJoint2Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint2SliderValueChanged, true);
            app.Joint1SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint1SliderLabel_2.Text = 'Joint 1';
            app.IRBJoint1Slider = uislider(app.UIFigure);
            app.IRBJoint1Slider.Limits = [-170 170];
            app.IRBJoint1Slider.ValueChangedFcn = createCallbackFcn(app, @IRBJoint1SliderValueChanged, true);
            app.YEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.IRBYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.IRBZEditField = uieditfield(app.UIFigure, 'numeric');
            app.XTextAreaLabel_2 = uilabel(app.UIFigure);
            app.XTextAreaLabel_2.Text = 'X';
            app.IRBXTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YTextAreaLabel_2.Text = 'Y';
            app.IRBYTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel_2 = uilabel(app.UIFigure);
            app.ZTextAreaLabel_2.Text = 'Z';
            app.IRBZTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YawTextAreaLabel_2.Text = 'Yaw';
            app.IRBYawTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PitchTextAreaLabel_2.Text = 'Pitch';
            app.IRBPitchTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel_2 = uilabel(app.UIFigure);
            app.RollTextAreaLabel_2.Text = 'Roll';
            app.IRBRollTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end